classdef fish_shark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        CalculateButton                 matlab.ui.control.Button
        ModifiedPredetorPreyModelLabel  matlab.ui.control.Label
        ddtfa1fb1fsc1fLabel             matlab.ui.control.Label
        ddtsa2sb2sfc2sLabel             matlab.ui.control.Label
        a1EditFieldLabel                matlab.ui.control.Label
        a1                              matlab.ui.control.NumericEditField
        a2EditFieldLabel                matlab.ui.control.Label
        a2                              matlab.ui.control.NumericEditField
        b1Label                         matlab.ui.control.Label
        b1                              matlab.ui.control.NumericEditField
        b2Label                         matlab.ui.control.Label
        b2                              matlab.ui.control.NumericEditField
        x0EditFieldLabel                matlab.ui.control.Label
        x0                              matlab.ui.control.NumericEditField
        tmaxEditFieldLabel              matlab.ui.control.Label
        tmax                            matlab.ui.control.NumericEditField
        y0EditFieldLabel                matlab.ui.control.Label
        y0                              matlab.ui.control.NumericEditField
        a1EditFieldLabel_2              matlab.ui.control.Label
        c1                              matlab.ui.control.NumericEditField
        a1EditFieldLabel_3              matlab.ui.control.Label
        c2                              matlab.ui.control.NumericEditField
        Switch                          matlab.ui.control.Switch
        AnimationLabel                  matlab.ui.control.Label
        stop                            matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            [yy,t]=RK4(@fish_sharks,app.x0.Value,app.y0.Value,0,app.tmax.Value,500,app.a1.Value,app.b1.Value,app.c1.Value,app.a2.Value,app.b2.Value,app.c2.Value);
               plot(app.UIAxes,yy(:,1),yy(:,2),'o');
               app.UIAxes.XTickLabel=yy(:,1);
            app.UIAxes.YTickLabel=yy(:,2);
            plot(app.UIAxes_3,t,yy(:,1),'g');
               app.UIAxes_3.XTickLabel=t(1,:);
            app.UIAxes_3.YTickLabel=yy(:,2);
            plot(app.UIAxes_2,t,yy(:,2));
            app.UIAxes_2.XTickLabel=t(1,:);
            app.UIAxes_2.YTickLabel=yy(:,1);
             if app.Switch.Value=="On"
                    a=length(yy(:,1));
                    figure(1);
                   for i=1:10:a
                          f=fix(yy(i,1));
                          s=fix(yy(i,2));
                          subplot(121);
                          scatter(rand(s,1),rand(s,1),20,'b','o');hold on;
                          title(['Time=' num2str(i/50) ' ;Sharks=' num2str(s) ]);
                          subplot(122);
                          scatter(rand(f,1),rand(f,1),20,'g','*');
                          title([' Fish=' num2str(f)]);
                          pause(1);
                          if (i~=100)   
                                clf;
                          end
                          disp([i s f]);
                          if app.stop.Value==true
                              break
                          end
                   end
                   close all;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ModifiedPredetorPreyModelLabel = uilabel(app.UIFigure);
            app.ModifiedPredetorPreyModelLabel.Text = 'Modified Predetor-Prey Model';
            app.ddtfa1fb1fsc1fLabel = uilabel(app.UIFigure);
            app.ddtfa1fb1fsc1fLabel.Text = 'd/dt(f)=a1*f - b1*fs- c1*f';
            app.ddtsa2sb2sfc2sLabel = uilabel(app.UIFigure);
            app.ddtsa2sb2sfc2sLabel.Text = 'd/dt(s)=-a2*s + b2*sf - c2*s';
            app.a1EditFieldLabel = uilabel(app.UIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1 = uieditfield(app.UIFigure, 'numeric');
            app.a1.Value = 0.48069;
            app.a2EditFieldLabel = uilabel(app.UIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2 = uieditfield(app.UIFigure, 'numeric');
            app.a2.Value = 0.92718;
            app.b1Label = uilabel(app.UIFigure);
            app.b1Label.Text = 'b1';
            app.b1 = uieditfield(app.UIFigure, 'numeric');
            app.b1.Value = 0.024822;
            app.b2Label = uilabel(app.UIFigure);
            app.b2Label.Text = 'b2';
            app.b2 = uieditfield(app.UIFigure, 'numeric');
            app.b2.Value = 0.027564;
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0 = uieditfield(app.UIFigure, 'numeric');
            app.x0.Value = 11;
            app.tmaxEditFieldLabel = uilabel(app.UIFigure);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmax = uieditfield(app.UIFigure, 'numeric');
            app.tmax.Value = 20;
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0 = uieditfield(app.UIFigure, 'numeric');
            app.y0.Value = 10;
            app.a1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.a1EditFieldLabel_2.Text = 'c1';
            app.c1 = uieditfield(app.UIFigure, 'numeric');
            app.c1.Value = 0.05;
            app.a1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.a1EditFieldLabel_3.Text = 'c2';
            app.c2 = uieditfield(app.UIFigure, 'numeric');
            app.c2.Value = 0.01;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.AnimationLabel = uilabel(app.UIFigure);
            app.AnimationLabel.Text = 'Animation';
            app.stop = uicheckbox(app.UIFigure);
            app.stop.Text = 'Stop animation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = fish_shark

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end