classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        CalculateButton           matlab.ui.control.Button
        Predetors1PreyModelLabel  matlab.ui.control.Label
        ddtxa1xb1xyc1xzLabel      matlab.ui.control.Label
        ddtya2yb2xyLabel          matlab.ui.control.Label
        a3Label                   matlab.ui.control.Label
        a3                        matlab.ui.control.NumericEditField
        b1Label                   matlab.ui.control.Label
        b1                        matlab.ui.control.NumericEditField
        b2Label                   matlab.ui.control.Label
        b2                        matlab.ui.control.NumericEditField
        b3Label                   matlab.ui.control.Label
        b3                        matlab.ui.control.NumericEditField
        x0EditFieldLabel          matlab.ui.control.Label
        x0                        matlab.ui.control.NumericEditField
        tmaxEditFieldLabel        matlab.ui.control.Label
        tmax                      matlab.ui.control.NumericEditField
        y0EditFieldLabel          matlab.ui.control.Label
        y0                        matlab.ui.control.NumericEditField
        a2Label                   matlab.ui.control.Label
        a2                        matlab.ui.control.NumericEditField
        a1Label                   matlab.ui.control.Label
        a1                        matlab.ui.control.NumericEditField
        ddtza3zb3xzLabel          matlab.ui.control.Label
        c1Label                   matlab.ui.control.Label
        c1                        matlab.ui.control.NumericEditField
        z0Label                   matlab.ui.control.Label
        z0                        matlab.ui.control.NumericEditField
        UIAxes_2                  matlab.ui.control.UIAxes
        pretredpredetor1blueprdetor3greenLabel  matlab.ui.control.Label
        Switch                    matlab.ui.control.Switch
        AnimationLabel            matlab.ui.control.Label
        stop                      matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            [yy,t]=RK4_3(@Predetor2_Prey1,app.x0.Value,app.y0.Value,app.z0.Value,0,app.tmax.Value,500,app.a1.Value,app.b1.Value,app.b3.Value,app.a2.Value,app.b2.Value,app.a3.Value,app.b3.Value);
           
            plot3(app.UIAxes,yy(:,1),yy(:,2),yy(:,3),'*');
            zlabel(app.UIAxes,'predetor 2');
              app.UIAxes.XTickLabel=yy(:,1);
            app.UIAxes.YTickLabel=yy(:,2);
            app.UIAxes.ZTickLabel=yy(:,3);
            plot(app.UIAxes_2,t,yy(:,1),'r',t,yy(:,2),'b',t,yy(:,3),'g');
              app.UIAxes_2.XTickLabel=t(1,:);
            app.UIAxes_2.YTickLabel=yy(:,1);
            
            if app.Switch.Value=="On"
                    a=length(yy(:,1));
                    figure(1);
                   for i=1:10:a
                         
                          r=fix(yy(i,1));
                          p1=fix(yy(i,2));
                          p2=fix(yy(i,3));
                          subplot(131);
                          scatter(rand(p1,1),rand(p1,1),20,'b','o');hold on;
                          title(['Time=' num2str(i/50) ' ;predetor1=' num2str(p1) ]);
                          subplot(132);
                          scatter(rand(p2,1),rand(p2,1),20,'green','x');hold on;
                          title(['Time=' num2str(i/50) ' ;predetor2=' num2str(p2) ]);
                          subplot(133);
                          scatter(rand(r,1),rand(r,1),20,'r','*');
                          title([' prey=' num2str(r)]);
                          pause(1);
                          if (i~=501)   
                               clf;
                          end
                          disp([i p1 p2 r]);
                         
                          if app.stop.Value==true
                              break
                          end
                   end
                   close all;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'prey')
            ylabel(app.UIAxes, 'predetor 1')
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Predetors1PreyModelLabel = uilabel(app.UIFigure);
            app.Predetors1PreyModelLabel.Text = '2Predetors-1Prey Model';
            app.ddtxa1xb1xyc1xzLabel = uilabel(app.UIFigure);
            app.ddtxa1xb1xyc1xzLabel.Text = 'd/dt(x)=a1*x- b1*xy-c1*xz';
            app.ddtya2yb2xyLabel = uilabel(app.UIFigure);
            app.ddtya2yb2xyLabel.Text = 'd/dt(y)=-a2*y + b2*xy';
            app.a3Label = uilabel(app.UIFigure);
            app.a3Label.Text = 'a3';
            app.a3 = uieditfield(app.UIFigure, 'numeric');
            app.a3.Value = 1;
            app.b1Label = uilabel(app.UIFigure);
            app.b1Label.Text = 'b1';
            app.b1 = uieditfield(app.UIFigure, 'numeric');
            app.b1.Value = 0.005;
            app.b2Label = uilabel(app.UIFigure);
            app.b2Label.Text = 'b2';
            app.b2 = uieditfield(app.UIFigure, 'numeric');
            app.b2.Value = 0.005;
            app.b3Label = uilabel(app.UIFigure);
            app.b3Label.Text = 'b3';
            app.b3 = uieditfield(app.UIFigure, 'numeric');
            app.b3.Value = 0.005;
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0 = uieditfield(app.UIFigure, 'numeric');
            app.x0.Value = 300;
            app.tmaxEditFieldLabel = uilabel(app.UIFigure);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmax = uieditfield(app.UIFigure, 'numeric');
            app.tmax.Value = 20;
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0 = uieditfield(app.UIFigure, 'numeric');
            app.y0.Value = 150;
            app.a2Label = uilabel(app.UIFigure);
            app.a2Label.Text = 'a2';
            app.a2 = uieditfield(app.UIFigure, 'numeric');
            app.a2.Value = 1;
            app.a1Label = uilabel(app.UIFigure);
            app.a1Label.Text = 'a1';
            app.a1 = uieditfield(app.UIFigure, 'numeric');
            app.a1.Value = 2;
            app.ddtza3zb3xzLabel = uilabel(app.UIFigure);
            app.ddtza3zb3xzLabel.Text = 'd/dt(z)=-a3*z + b3*xz';
            app.c1Label = uilabel(app.UIFigure);
            app.c1Label.Text = 'c1';
            app.c1 = uieditfield(app.UIFigure, 'numeric');
            app.c1.Value = 0.005;
            app.z0Label = uilabel(app.UIFigure);
            app.z0Label.Text = 'z0';
            app.z0 = uieditfield(app.UIFigure, 'numeric');
            app.z0.Value = 140;
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'Time')
            app.UIAxes_2.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.pretredpredetor1blueprdetor3greenLabel = uilabel(app.UIFigure);
            app.pretredpredetor1blueprdetor3greenLabel.Text = 'pret: red , predetor1: blue,prdetor3: green';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.AnimationLabel = uilabel(app.UIFigure);
            app.AnimationLabel.Text = 'Animation';
            app.stop = uicheckbox(app.UIFigure);
            app.stop.Text = 'Stop animation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end