classdef appCompetitive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        CompetitiveLogisticModelLabel  matlab.ui.control.Label
        ddtxa1xa1x2K1bxyK1Label        matlab.ui.control.Label
        ddtya2ya2y2K1cxyK1Label        matlab.ui.control.Label
        a1EditFieldLabel               matlab.ui.control.Label
        a1                             matlab.ui.control.NumericEditField
        a2EditFieldLabel               matlab.ui.control.Label
        a2                             matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        b                              matlab.ui.control.NumericEditField
        cEditFieldLabel                matlab.ui.control.Label
        c                              matlab.ui.control.NumericEditField
        x0EditFieldLabel               matlab.ui.control.Label
        x0                             matlab.ui.control.NumericEditField
        tmaxEditFieldLabel             matlab.ui.control.Label
        tmax                           matlab.ui.control.NumericEditField
        y0EditFieldLabel               matlab.ui.control.Label
        y0                             matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        K2Label                        matlab.ui.control.Label
        K2                             matlab.ui.control.NumericEditField
        K1Label                        matlab.ui.control.Label
        K1                             matlab.ui.control.NumericEditField
        Switch                         matlab.ui.control.Switch
        AnimationLabel                 matlab.ui.control.Label
        stop                           matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Ia1=app.a1.Value;
            Ia2=app.a2.Value;
            Ib=app.b.Value;
            Ic=app.c.Value;
            Itm=app.tmax.Value;
            IX0=app.x0.Value;
            IY0=app.y0.Value;
            IK1=app.K1.Value;
            IK2=app.K2.Value;
         
            [yy,t]=rk4(@f2,IX0,IY0,0,Itm,500,Ia1,Ia2,Ib,Ic,IK1,IK2);
             plot(app.UIAxes,yy(:,1),yy(:,2),'x');
              app.UIAxes.XTickLabel=yy(:,1);
            app.UIAxes.YTickLabel=yy(:,2);
            plot(app.UIAxes_3,t,yy(:,1),'r');
              app.UIAxes_3.XTickLabel=t(1,:);
            app.UIAxes_3.YTickLabel=yy(:,2);
            plot(app.UIAxes_2,t,yy(:,2),'g');
              app.UIAxes_2.XTickLabel=t(1,:);
            app.UIAxes_2.YTickLabel=yy(:,1);
            
            if app.Switch.Value=="On"
                    a=length(yy(:,1));
                    figure(1);
                   for i=1:10:a
                           s1=fix(yy(i,1));
                           s2=fix(yy(i,2));
                           subplot(121);
                           scatter(rand(s2,1),rand(s2,1),20,'green','o');hold on;
                           title(['Time=' num2str(i/50) ' ;species2=' num2str(s2) ]);
                           subplot(122);
                           scatter(rand(s1,1),rand(s1,1),20,'magenta','*');
                           title([' species1=' num2str(s1)]);
                           pause(1);
                           if (i~=501)   
                                clf;
                           end
                          disp([i s2 s1]);
                          if app.stop.Value==true
                              break
                          end
                   end
                   close all;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.CompetitiveLogisticModelLabel = uilabel(app.UIFigure);
            app.CompetitiveLogisticModelLabel.Text = 'Competitive-Logistic Model';
            app.ddtxa1xa1x2K1bxyK1Label = uilabel(app.UIFigure);
            app.ddtxa1xa1x2K1bxyK1Label.Text = 'd/dt(x)=a1*x - (a1*(x^2)/K1) - ((b*xy)/K1)';
            app.ddtya2ya2y2K1cxyK1Label = uilabel(app.UIFigure);
            app.ddtya2ya2y2K1cxyK1Label.Text = 'd/dt(y)=a2*y - (a2*(y^2)/K1) - ((c*xy)/K1)';
            app.a1EditFieldLabel = uilabel(app.UIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1 = uieditfield(app.UIFigure, 'numeric');
            app.a1.Value = 0.9;
            app.a2EditFieldLabel = uilabel(app.UIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2 = uieditfield(app.UIFigure, 'numeric');
            app.a2.Value = 0.5;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.b = uieditfield(app.UIFigure, 'numeric');
            app.b.Value = 0.6;
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.c = uieditfield(app.UIFigure, 'numeric');
            app.c.Value = 0.7;
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0 = uieditfield(app.UIFigure, 'numeric');
            app.x0.Value = 11;
            app.tmaxEditFieldLabel = uilabel(app.UIFigure);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmax = uieditfield(app.UIFigure, 'numeric');
            app.tmax.Value = 20;
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0 = uieditfield(app.UIFigure, 'numeric');
            app.y0.Value = 10;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.K2Label = uilabel(app.UIFigure);
            app.K2Label.Text = 'K2';
            app.K2 = uieditfield(app.UIFigure, 'numeric');
            app.K2.Value = 1000;
            app.K1Label = uilabel(app.UIFigure);
            app.K1Label.Text = 'K1';
            app.K1 = uieditfield(app.UIFigure, 'numeric');
            app.K1.Value = 500;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.AnimationLabel = uilabel(app.UIFigure);
            app.AnimationLabel.Text = 'Animation';
            app.stop = uicheckbox(app.UIFigure);
            app.stop.Text = 'Stop animation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appCompetitive

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end